classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InductionMotorAnalyisisUIFigure  matlab.ui.Figure
        UIAxes        matlab.ui.control.UIAxes
        UITable       matlab.ui.control.Table
        UIAxes2       matlab.ui.control.UIAxes
        ExitButton    matlab.ui.control.Button
        ExportButton  matlab.ui.control.Button
        InfoButton    matlab.ui.control.Button
    end


    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data
            x2 = t.Age;
            y2 = t.BloodPressure(:,2);
            plot(app.UIAxes2,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            t = readtable('patients.xls');
            vars = {'Age','Systolic','Diastolic','SelfAssessedHealthStatus','Smoker'};
            
            % Select a subset of the table array
            t = t(1:20,vars);
            
            % Sort the data by age
            t = sortrows(t,'Age');
            
            % Combine Systolic and Diastolic into one variable
            t.BloodPressure = [t.Systolic t.Diastolic];
            t.Systolic = [];
            t.Diastolic = [];
            
            % Convert SelfAssessedHealthStatus to categorical
            cats = categorical(t.SelfAssessedHealthStatus,{'Poor','Fair','Good','Excellent'});
            t.SelfAssessedHealthStatus = cats;
            
            % Rearrange columns
            t = t(:,[1 4 3 2]);
            
            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            % Plot the original data
            x1 = app.UITable.Data.Age;
            y1 = app.UITable.Data.BloodPressure(:,2);
            plot(app.UIAxes,x1,y1,'o-');
            
            % Plot the data
            updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.InductionMotorAnalyisisUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InductionMotorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.InductionMotorAnalyisisUIFigure.Name = 'Induction Motor Analyisis';
            app.UIAxes = uiaxes(app.InductionMotorAnalyisisUIFigure);
            title(app.UIAxes, {'Torque (Nm)'; ''})
            xlabel(app.UIAxes, 'Speed (rpm)')
            ylabel(app.UIAxes, '')
            app.UITable = uitable(app.InductionMotorAnalyisisUIFigure);
            app.UITable.ColumnName = {'Zth'; 'τ'; 'I2'; 'ωm'; 'P_Gap'; 'P_Conv'; 'P_Loss'; 'Pull_out τ'; 'η(%)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UIAxes2 = uiaxes(app.InductionMotorAnalyisisUIFigure);
            title(app.UIAxes2, 'Torque-Speed Curve')
            xlabel(app.UIAxes2, 'Torque (Nm)')
            ylabel(app.UIAxes2, 'Slip')
            app.ExitButton = uibutton(app.InductionMotorAnalyisisUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ExportButton = uibutton(app.InductionMotorAnalyisisUIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.InfoButton = uibutton(app.InductionMotorAnalyisisUIFigure, 'push');
            app.InfoButton.Text = 'Info';
            % Show the figure after all components are created
            app.InductionMotorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InductionMotorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InductionMotorAnalyisisUIFigure)
        end
    end
end